classdef Parrot_minidrone_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        UDPreceptionofmindroneangularrateTab  matlab.ui.container.Tab
        MaxSimulationtimeButtonGroup  matlab.ui.container.ButtonGroup
        ResetButton                   matlab.ui.control.Button
        MaxSimulationtimesEditField   matlab.ui.control.NumericEditField
        StartStopplotingButtonGroup   matlab.ui.container.ButtonGroup
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UDPinitialisationButtonGroup  matlab.ui.container.ButtonGroup
        StartInitialisationButton     matlab.ui.control.Button
        SelectTragetButtonGroup       matlab.ui.container.ButtonGroup
        MamboButton                   matlab.ui.control.RadioButton
        RollingSpiderButton           matlab.ui.control.RadioButton
        SetParamettersBuildModelButtonGroup  matlab.ui.container.ButtonGroup
        BuildonTargetButton           matlab.ui.control.Button
        EditmodelButton               matlab.ui.control.Button
        OpenModelButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        DocumentationTab              matlab.ui.container.Tab
        TextArea_2Label_3             matlab.ui.control.Label
        Hyperlink3                    matlab.ui.control.Hyperlink
        TextArea_2Label_2             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Hyperlink2                    matlab.ui.control.Hyperlink
        TextArea_2Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        u;
        StopPloting;
        data;
        j;
        t;
        maxTime;
        isModelEdited;
        TargetName;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data=single(0);
            app.j=0;
            app.t=0;
            app.maxTime=30;
            app.isModelEdited=20; % Could be any value outside the range [0 - 10]
            app.TargetName='PARROT Rolling Spider';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StopPloting=0;  
            while ((app.StopPloting==0) & (app.t<app.maxTime))
                if (app.u.NumDatagramsAvailable > 0)  % if udp was received
                    % read udp
                    Count=app.u.NumDatagramsAvailable;
                    Datagram= read(app.u, app.u.NumDatagramsAvailable, "single");
                    for i=1:Count 
                    app.data(app.j+i)=Datagram(1,i).Data;
                    app.t(app.j+i)=(app.j+i)*0.05;
                    end
                    if(app.t<5)
                    app.UIAxes.XLim=[0 app.t(app.j+Count)+5];
                    else
                    app.UIAxes.XLim=[app.t(app.j+Count)-5 app.t(app.j+Count)+5];  
                    end
                    plot(app.UIAxes,app.t,app.data);
                    app.j=app.j+Count;
                    pause(0.05);

                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopPloting =1;
        end

        % Value changed function: MaxSimulationtimesEditField
        function MaxSimulationtimesEditFieldValueChanged(app, event)
            app.maxTime = app.MaxSimulationtimesEditField.Value;
        end

        % Button pushed function: StartInitialisationButton
        function StartInitialisationButtonPushed(app, event)
            app.u=udpport("datagram","IPV4","LocalPort",29000,"LocalHost","192.168.3.2","ByteOrder","little-endian");
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            open_system('parrot_communication');
        end

        % Button pushed function: EditmodelButton
        function EditmodelButtonPushed(app, event)

            switch app.RollingSpiderButton.Value
                 case 1
                       set_param('parrot_communication','HardwareBoard','PARROT Rolling Spider','MatFileLogging','on');
                 case 0
                       set_param('parrot_communication','HardwareBoard','PARROT Mambo','MatFileLogging','on');  
                 otherwise
                       warning('Unexpected Target type !')
            end

            tmp1=get_param('parrot_communication/Flight_Control_System/Subsystem1','PortConnectivity');
            app.isModelEdited=tmp1(1);
            app.TargetName=get_param('parrot_communication','HardwareBoard');
            
            if(app.isModelEdited.SrcPort==3)
                    try 
                    set_param('parrot_communication/Flight_Control_System/Pulse Generator', 'Period', '10');
                    delete_line('parrot_communication/Flight_Control_System/','Subsystem/4','Subsystem1/1')
                    delete_line('parrot_communication/Flight_Control_System/','Subsystem/5','Terminator12/1')
                    Pos1=get_param('parrot_communication/Flight_Control_System/Terminator10','Position');
                    Pos2=get_param('parrot_communication/Flight_Control_System/Terminator9','Position');
                    set_param('parrot_communication/Flight_Control_System/Terminator12','Position',[Pos1(1) 2*Pos1(2)-Pos2(2) Pos1(3) 2*Pos1(4)-Pos2(4)])
                    add_line('parrot_communication/Flight_Control_System/','Subsystem/4','Terminator12/1')
                    add_line('parrot_communication/Flight_Control_System/','Subsystem/5','Subsystem1/1','autorouting','on')
                        
                    catch Message_err
                        display(Message_err.message)
                    end
                    if ~exist('Message_err', 'var')
                          msgbox('Operation Completed Successfully ');
                    else
                          msgbox("An error occured !");
                          rethrow(Message_err);
                    end
            else 
                    msgbox('The Simulink model has already been modified');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clear app.data app.j app.t 
            app.data=single(0);
            app.j=0;
            app.t=0;
        end

        % Button pushed function: BuildonTargetButton
        function BuildonTargetButtonPushed(app, event)
            rtwbuild('parrot_communication');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close_system('parrot_communication','off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UDPreceptionofmindroneangularrateTab = uitab(app.TabGroup);
            app.UDPreceptionofmindroneangularrateTab.Title = 'UDP reception of mindrone angular rate ';
            app.UIAxes = uiaxes(app.UDPreceptionofmindroneangularrateTab);
            xlabel(app.UIAxes, 'Times [s]')
            ylabel(app.UIAxes, 'Angular rate')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.LineStyleOrder = {'-'};
            app.SetParamettersBuildModelButtonGroup = uibuttongroup(app.UDPreceptionofmindroneangularrateTab);
            app.SetParamettersBuildModelButtonGroup.Title = 'Set Parametters & Build Model';
            app.OpenModelButton = uibutton(app.SetParamettersBuildModelButtonGroup, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = 'Open Model';
            app.EditmodelButton = uibutton(app.SetParamettersBuildModelButtonGroup, 'push');
            app.EditmodelButton.ButtonPushedFcn = createCallbackFcn(app, @EditmodelButtonPushed, true);
            app.EditmodelButton.Text = 'Edit model';
            app.BuildonTargetButton = uibutton(app.SetParamettersBuildModelButtonGroup, 'push');
            app.BuildonTargetButton.ButtonPushedFcn = createCallbackFcn(app, @BuildonTargetButtonPushed, true);
            app.BuildonTargetButton.Text = 'Build on Target';
            app.SelectTragetButtonGroup = uibuttongroup(app.UDPreceptionofmindroneangularrateTab);
            app.SelectTragetButtonGroup.Title = 'Select Traget';
            app.RollingSpiderButton = uiradiobutton(app.SelectTragetButtonGroup);
            app.RollingSpiderButton.Text = 'Rolling Spider';
            app.RollingSpiderButton.Value = true;
            app.MamboButton = uiradiobutton(app.SelectTragetButtonGroup);
            app.MamboButton.Text = 'Mambo';
            app.UDPinitialisationButtonGroup = uibuttongroup(app.UDPreceptionofmindroneangularrateTab);
            app.UDPinitialisationButtonGroup.Title = 'UDP initialisation ';
            app.StartInitialisationButton = uibutton(app.UDPinitialisationButtonGroup, 'push');
            app.StartInitialisationButton.ButtonPushedFcn = createCallbackFcn(app, @StartInitialisationButtonPushed, true);
            app.StartInitialisationButton.Text = 'Start Initialisation';
            app.StartStopplotingButtonGroup = uibuttongroup(app.UDPreceptionofmindroneangularrateTab);
            app.StartStopplotingButtonGroup.Title = 'Start & Stop ploting';
            app.StartButton = uibutton(app.StartStopplotingButtonGroup, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.StartStopplotingButtonGroup, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MaxSimulationtimeButtonGroup = uibuttongroup(app.UDPreceptionofmindroneangularrateTab);
            app.MaxSimulationtimeButtonGroup.Title = 'Max Simulation time ';
            app.MaxSimulationtimesEditField = uieditfield(app.MaxSimulationtimeButtonGroup, 'numeric');
            app.MaxSimulationtimesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxSimulationtimesEditFieldValueChanged, true);
            app.MaxSimulationtimesEditField.Value = 30;
            app.ResetButton = uibutton(app.MaxSimulationtimeButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DocumentationTab = uitab(app.TabGroup);
            app.DocumentationTab.Title = 'Documentation';
            app.TextArea_2Label = uilabel(app.DocumentationTab);
            app.TextArea_2Label.Text = 'Check Mathworks website for ';
            app.Hyperlink2 = uihyperlink(app.DocumentationTab);
            app.Hyperlink2.URL = 'https://www.mathworks.com/help/supportpkg/parrot/setup-and-configuration.html';
            app.Hyperlink2.Text = 'Setup and Configuration';
            app.Image = uiimage(app.DocumentationTab);
            app.Image.ImageSource = 'parrot-minidrones1.png';
            app.TextArea_2Label_2 = uilabel(app.DocumentationTab);
            app.TextArea_2Label_2.Text = 'Source Code : ';
            app.Hyperlink3 = uihyperlink(app.DocumentationTab);
            app.Hyperlink3.URL = 'https://github.com/ElhoumYassine';
            app.Hyperlink3.Text = 'Github';
            app.TextArea_2Label_3 = uilabel(app.DocumentationTab);
            app.TextArea_2Label_3.Text = 'Contact (email) : yassineelhoum@research.emi.ac.ma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parrot_minidrone_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end